classdef DoubleChamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        Image                   matlab.ui.control.Image
        HealthLabel             matlab.ui.control.Label
        ResourceLabel           matlab.ui.control.Label
        AttackDamageLabel       matlab.ui.control.Label
        ArmorLabel              matlab.ui.control.Label
        AttackSpeedLabel        matlab.ui.control.Label
        CriticalChanceLabel     matlab.ui.control.Label
        HealthVal               matlab.ui.control.Label
        ResourceVal             matlab.ui.control.Label
        AttackDamageVal         matlab.ui.control.Label
        ArmorVal                matlab.ui.control.Label
        AttackSpeedVal          matlab.ui.control.Label
        CriticalChanceVal       matlab.ui.control.Label
        AbilityPowerLabel       matlab.ui.control.Label
        MagicResistanceLabel    matlab.ui.control.Label
        AbilityHasteLabel       matlab.ui.control.Label
        MovementSpeedLabel      matlab.ui.control.Label
        AbilityPowerVal         matlab.ui.control.Label
        MagicResistanceVal      matlab.ui.control.Label
        AbilityHasteVal         matlab.ui.control.Label
        MovementSpeedVal        matlab.ui.control.Label
        Slot11Im                matlab.ui.control.Image
        Slot12Im                matlab.ui.control.Image
        Slot13Im                matlab.ui.control.Image
        Slot14Im                matlab.ui.control.Image
        Slot15Im                matlab.ui.control.Image
        Slot16Im                matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image2_2                matlab.ui.control.Image
        Image2_3                matlab.ui.control.Image
        Image2_4                matlab.ui.control.Image
        Image2_5                matlab.ui.control.Image
        Image2_6                matlab.ui.control.Image
        Image2_7                matlab.ui.control.Image
        Image2_8                matlab.ui.control.Image
        Image2_9                matlab.ui.control.Image
        Image2_10               matlab.ui.control.Image
        Image_2                 matlab.ui.control.Image
        HealthLabel_2           matlab.ui.control.Label
        ResourceLabel_2         matlab.ui.control.Label
        AttackDamageLabel_2     matlab.ui.control.Label
        ArmorLabel_2            matlab.ui.control.Label
        AttackSpeedLabel_2      matlab.ui.control.Label
        CriticalChanceLabel_2   matlab.ui.control.Label
        HealthVal_2             matlab.ui.control.Label
        ResourceVal_2           matlab.ui.control.Label
        AttackDamageVal_2       matlab.ui.control.Label
        ArmorVal_2              matlab.ui.control.Label
        AttackSpeedVal_2        matlab.ui.control.Label
        CriticalChanceVal_2     matlab.ui.control.Label
        AbilityPowerLabel_2     matlab.ui.control.Label
        MagicResistanceLabel_2  matlab.ui.control.Label
        AbilityHasteLabel_2     matlab.ui.control.Label
        MovementSpeedLabel_2    matlab.ui.control.Label
        AbilityPowerVal_2       matlab.ui.control.Label
        MagicResistanceVal_2    matlab.ui.control.Label
        AbilityHasteVal_2       matlab.ui.control.Label
        MovementSpeedVal_2      matlab.ui.control.Label
        Slot21Im                matlab.ui.control.Image
        Slot22Im                matlab.ui.control.Image
        Slot23Im                matlab.ui.control.Image
        Slot24Im                matlab.ui.control.Image
        Slot25Im                matlab.ui.control.Image
        Slot26Im                matlab.ui.control.Image
        Image2_11               matlab.ui.control.Image
        Image2_12               matlab.ui.control.Image
        Image2_13               matlab.ui.control.Image
        Image2_14               matlab.ui.control.Image
        Image2_15               matlab.ui.control.Image
        Image2_16               matlab.ui.control.Image
        Image2_17               matlab.ui.control.Image
        Image2_18               matlab.ui.control.Image
        Image2_19               matlab.ui.control.Image
        Image2_20               matlab.ui.control.Image
        Q1                      matlab.ui.control.Image
        W1                      matlab.ui.control.Image
        E1                      matlab.ui.control.Image
        R1                      matlab.ui.control.Image
        MagicPenLabel           matlab.ui.control.Label
        MagicPenVal             matlab.ui.control.Label
        ArmorPenLabel           matlab.ui.control.Label
        ArmorPenVal             matlab.ui.control.Label
        LethalityLabel          matlab.ui.control.Label
        LethalityVal            matlab.ui.control.Label
        MagicPenLabel_2         matlab.ui.control.Label
        MagicPenVal_2           matlab.ui.control.Label
        ArmorPenLabel_2         matlab.ui.control.Label
        ArmorPenVal_2           matlab.ui.control.Label
        LethalityLabel_2        matlab.ui.control.Label
        LethalityVal_2          matlab.ui.control.Label
        Q2                      matlab.ui.control.Image
        W2                      matlab.ui.control.Image
        E2                      matlab.ui.control.Image
        R2                      matlab.ui.control.Image
        TabGroup                matlab.ui.container.TabGroup
        Tab1                    matlab.ui.container.Tab
        effectAttributeLabel1   matlab.ui.control.Label
        effectValueLabel1       matlab.ui.control.Label
        effectDealtLabel1       matlab.ui.control.Label
        Utility1                matlab.ui.control.Label
        Enact1                  matlab.ui.control.StateButton
        Tab2                    matlab.ui.container.Tab
        effectAttributeLabel2   matlab.ui.control.Label
        effectValueLabel2       matlab.ui.control.Label
        effectDealtLabel2       matlab.ui.control.Label
        Utility2                matlab.ui.control.Label
        Enact2                  matlab.ui.control.StateButton
        Tab3                    matlab.ui.container.Tab
        effectAttributeLabel3   matlab.ui.control.Label
        effectValueLabel3       matlab.ui.control.Label
        effectDealtLabel3       matlab.ui.control.Label
        Utility3                matlab.ui.control.Label
        Enact3                  matlab.ui.control.StateButton
        Tab4                    matlab.ui.container.Tab
        effectAttributeLabel4   matlab.ui.control.Label
        effectValueLabel4       matlab.ui.control.Label
        effectDealtLabel4       matlab.ui.control.Label
        Utility4                matlab.ui.control.Label
        Enact4                  matlab.ui.control.StateButton
        Tab5                    matlab.ui.container.Tab
        effectAttributeLabel5   matlab.ui.control.Label
        effectValueLabel5       matlab.ui.control.Label
        effectDealtLabel5       matlab.ui.control.Label
        Utility5                matlab.ui.control.Label
        Enact5                  matlab.ui.control.StateButton
        Tab6                    matlab.ui.container.Tab
        effectAttributeLabel6   matlab.ui.control.Label
        effectValueLabel6       matlab.ui.control.Label
        effectDealtLabel6       matlab.ui.control.Label
        Utility6                matlab.ui.control.Label
        Enact6                  matlab.ui.control.StateButton
        Tab7                    matlab.ui.container.Tab
        effectAttributeLabel7   matlab.ui.control.Label
        effectValueLabel7       matlab.ui.control.Label
        effectDealtLabel7       matlab.ui.control.Label
        Utility7                matlab.ui.control.Label
        Enact7                  matlab.ui.control.StateButton
        Tab8                    matlab.ui.container.Tab
        effectAttributeLabel8   matlab.ui.control.Label
        effectValueLabel8       matlab.ui.control.Label
        effectDealtLabel8       matlab.ui.control.Label
        Utility8                matlab.ui.control.Label
        Enact8                  matlab.ui.control.StateButton
        Tab9                    matlab.ui.container.Tab
        effectAttributeLabel9   matlab.ui.control.Label
        effectValueLabel9       matlab.ui.control.Label
        effectDealtLabel9       matlab.ui.control.Label
        Utility9                matlab.ui.control.Label
        Enact9                  matlab.ui.control.StateButton
        Tab10                   matlab.ui.container.Tab
        effectAttributeLabel10  matlab.ui.control.Label
        effectValueLabel10      matlab.ui.control.Label
        effectDealtLabel10      matlab.ui.control.Label
        Utility10               matlab.ui.control.Label
        Enact10                 matlab.ui.control.StateButton
        Slot11                  matlab.ui.control.DropDown
        Slot12                  matlab.ui.control.DropDown
        Slot13                  matlab.ui.control.DropDown
        Slot14                  matlab.ui.control.DropDown
        Slot15                  matlab.ui.control.DropDown
        Slot16                  matlab.ui.control.DropDown
        Slot21                  matlab.ui.control.DropDown
        Slot22                  matlab.ui.control.DropDown
        Slot23                  matlab.ui.control.DropDown
        Slot24                  matlab.ui.control.DropDown
        Slot25                  matlab.ui.control.DropDown
        Slot26                  matlab.ui.control.DropDown
        ChampionName            matlab.ui.control.DropDown
        ChampionName2           matlab.ui.control.DropDown
        abi1                    matlab.ui.control.Label
        abi2                    matlab.ui.control.Label
        healthFull1             matlab.ui.control.Image
        resourceFull1           matlab.ui.control.Image
        healthFull2             matlab.ui.control.Image
        resourceFull2           matlab.ui.control.Image
        Effects                 matlab.ui.control.Label
        champ2Health            matlab.ui.control.Label
        champ1Health            matlab.ui.control.Label
        Redo1                   matlab.ui.control.Image
        Redo2                   matlab.ui.control.Image
        LvlDropDownLabel        matlab.ui.control.Label
        LvlDrop1                matlab.ui.control.DropDown
        LvlDropDownLabel_2      matlab.ui.control.Label
        LvlDrop2                matlab.ui.control.DropDown
        QSpinnerLabel           matlab.ui.control.Label
        QSpinner1               matlab.ui.control.Spinner
        ESpinnerLabel           matlab.ui.control.Label
        ESpinner1               matlab.ui.control.Spinner
        WSpinnerLabel           matlab.ui.control.Label
        WSpinner1               matlab.ui.control.Spinner
        RSpinnerLabel           matlab.ui.control.Label
        RSpinner1               matlab.ui.control.Spinner
        QSpinner_2Label         matlab.ui.control.Label
        QSpinner2               matlab.ui.control.Spinner
        ESpinner_2Label         matlab.ui.control.Label
        ESpinner2               matlab.ui.control.Spinner
        WSpinner_2Label         matlab.ui.control.Label
        WSpinner2               matlab.ui.control.Spinner
        RSpinner_2Label         matlab.ui.control.Label
        RSpinner2               matlab.ui.control.Spinner
        Image21                 matlab.ui.control.Image
        Image22                 matlab.ui.control.Image
        Image22_2               matlab.ui.control.Image
        Image22_3               matlab.ui.control.Image
        Image22_4               matlab.ui.control.Image
        Image21_2               matlab.ui.control.Image
        MejaiSlider1Label       matlab.ui.control.Label
        MejaiSlider1            matlab.ui.control.Spinner
        StackSlider1Label       matlab.ui.control.Label
        StackSlider1            matlab.ui.control.Spinner
        MejaiSlider2Label       matlab.ui.control.Label
        MejaiSlider2            matlab.ui.control.Spinner
        StackSlider2Label       matlab.ui.control.Label
        StackSlider2            matlab.ui.control.Spinner
        Patch111Label           matlab.ui.control.Label
    end

    
    properties (Access = public)
        itemDat  %Item data file
        champDat %Champion data file
        champ1   %Left champion structure
        champ2   %Right champion structure
        side     %Indicator for StatLoad
        itemKey  %Item keys 
        itemName %Item names
        invTemp  %Temporary inventory slot
        slot     %Slot in use
        champ    %Champ structure being referenced
        abiTemp  %Temporary ability slot
        Effect   %Effect structure
        Ref      %Reference for navigating effect structure
    end
    
    methods (Access = public)
        
        function StatLoad(app)
            %This function takes stats out of the champion structures and
            %displays them in the app
            switch app.side
                case 1
                    app.AttackDamageVal.Text = num2str(app.champ1.stats.attackDamage,'%.0f');
                    app.ArmorVal.Text = num2str(app.champ1.stats.armor,'%.0f');
                    app.AttackSpeedVal.Text = num2str(app.champ1.stats.attackSpeed,'%.2f');
                    app.CriticalChanceVal.Text = [num2str(app.champ1.stats.criticalStrikeChance,'%.0f'),'%'];
                    app.AbilityPowerVal.Text = num2str(app.champ1.stats.abilityPower,'%.0f');
                    app.MagicResistanceVal.Text = num2str(app.champ1.stats.magicResistance,'%.0f');
                    app.AbilityHasteVal.Text = num2str(app.champ1.stats.abilityHaste,'%.0f');
                    app.MovementSpeedVal.Text = num2str(app.champ1.stats.movespeed,'%.0f');
                    app.HealthVal.Text = num2str(app.champ1.stats.health,'%.0f');
                    app.ResourceVal.Text = num2str(app.champ1.stats.mana,'%0.f');
                    app.MagicPenVal.Text = [num2str(app.champ1.stats.magicPenetration,'%.0f'),'/',...
                        num2str(app.champ1.stats.magicPenPer,'%.0f'),'%'];
                    app.ArmorPenVal.Text = [num2str(app.champ1.stats.armorPenetration,'%.0f'),'%'];
                    app.LethalityVal.Text = num2str(app.champ1.stats.lethality,'%.0f');
                case 2
                    app.AttackDamageVal_2.Text = num2str(app.champ2.stats.attackDamage,'%.0f');
                    app.ArmorVal_2.Text = num2str(app.champ2.stats.armor,'%.0f');
                    app.AttackSpeedVal_2.Text = num2str(app.champ2.stats.attackSpeed,'%.2f');
                    app.CriticalChanceVal_2.Text = [num2str(app.champ2.stats.criticalStrikeChance,'%.0f'),'%'];
                    app.AbilityPowerVal_2.Text = num2str(app.champ2.stats.abilityPower,'%.0f');
                    app.MagicResistanceVal_2.Text = num2str(app.champ2.stats.magicResistance,'%.0f');
                    app.AbilityHasteVal_2.Text = num2str(app.champ2.stats.abilityHaste,'%.0f');
                    app.MovementSpeedVal_2.Text = num2str(app.champ2.stats.movespeed,'%.0f');
                    app.HealthVal_2.Text = num2str(app.champ2.stats.health,'%.0f');
                    app.ResourceVal_2.Text = num2str(app.champ2.stats.mana,'%0.f');
                    app.MagicPenVal_2.Text = [num2str(app.champ2.stats.magicPenetration,'%.0f'),'/',...
                        num2str(app.champ2.stats.magicPenPer,'%.0f'),'%'];
                    app.ArmorPenVal_2.Text = [num2str(app.champ2.stats.armorPenetration,'%.0f'),'%'];
                    app.LethalityVal_2.Text = num2str(app.champ2.stats.lethality,'%.0f');
            end
            QuickHealth(app,app.champ)
        end
        
        function ItemLoad(app)
            %This function processes user input in order to add items to
            %champion structure inventories. It also updates images.
            ind = strcmp(app.itemName,app.invTemp);
            item = app.itemKey(ind);
            [~,m,b,g] = ItemAdd(app.itemDat,app.(app.champ),item,app.slot);
            if m == 1
                uialert(app.UIFigure,'Only 1 mythic permitted per champion.','Item Alert');
                app.(['Slot',num2str(app.side),num2str(app.slot)]).Value = 'None';
                return
            elseif b == 1
                uialert(app.UIFigure,'Only 1 pair of boots permitted per champion.','Item Alert');
                app.(['Slot',num2str(app.side),num2str(app.slot)]).Value = 'None';
                return
            elseif g == 1
                uialert(app.UIFigure,'Only 1 glory item permitted per champion.','Item Alert');
                app.(['Slot',num2str(app.side),num2str(app.slot)]).Value = 'None';
                return
            end
            slotImName = ['Slot',num2str(app.side),num2str(app.slot),'Im'];
            if strcmp(app.invTemp,'None')
                app.(app.champ) = ItemRemove(app.itemDat,app.(app.champ),app.slot);
                app.(slotImName).ImageSource = 'square.png';
            else
                app.(app.champ) = ItemAdd(app.itemDat,app.(app.champ),item,app.slot);
                app.(slotImName).ImageSource = fullfile('item',[num2str(item),'.png']);
            end
            app.(app.champ) = PassiveUp(app.itemDat,app.(app.champ));
            StatLoad(app)
            MejaiSlider(app)
            if ~isempty(app.Effect)
                if ~isempty(app.abiTemp) && strcmp(app.(app.champ).ch,app.Effect(1).ch) && strcmp(app.abiTemp,app.Effect(1).key)
                    EffectLoad(app)
                end
            end
        end
        
        function AbiLoad(app)
            %This function updates ability icons and structure level by
            %interpreting user input. It also manages max ability levels.
            if any(strcmp(app.(app.champ).ch,{'Karma','Nidalee','Elise'}))
                Karma = 1;
                if app.(app.champ).abi.R == 0
                    app.(app.champ).abi.R = 1;
                end
            else 
                Karma = 0;
            end
            spent = 0;
            abi = 'QWER';
            for i = 1:length(abi)
                spent = spent + app.(app.champ).abi.(abi(i));
            end
            for i = 1:length(abi)
                switch abi(i)
                    case 'R'
                        if Karma == 1
                            maxLevel = floor((app.(app.champ).l-1)/5)+1;
                        elseif strcmp(app.(app.champ).ch,'Udyr')
                            maxLevel = floor((app.(app.champ).l-1)/5);
                        else
                            maxLevel = floor((app.(app.champ).l-1)/5);
                        end
                    otherwise
                        maxLevel = ceil(app.(app.champ).l/2);
                        if strcmp(app.(app.champ).ch,'Udyr')
                            altLevel = 6;
                        else
                            altLevel = 5;
                        end
                        if maxLevel > altLevel
                            maxLevel = altLevel;
                        end
                end
                if app.(app.champ).l - spent + Karma == 0
                    maxLevel = app.(app.champ).abi.(abi(i));
                end
                if maxLevel == 0
                    app.([abi(i),'Spinner',num2str(app.side)]).Visible = 0;
                else
                    app.([abi(i),'Spinner',num2str(app.side)]).Visible = 1;
                    app.([abi(i),'Spinner',num2str(app.side)]).Limits = [0,maxLevel];
                    if Karma == 1 && strcmp(abi(i),'R')
                        if maxLevel == 1
                            app.([abi(i),'Spinner',num2str(app.side)]).Visible = 0;
                        else
                            app.([abi(i),'Spinner',num2str(app.side)]).Limits = [1,maxLevel];
                        end
                    end
                end
            end
            switch app.(app.champ).l - spent + Karma
                case 0
                    app.(['abi',num2str(app.side)]).Text = 'No points left to spend!';
                case 1
                    app.(['abi',num2str(app.side)]).Text = '1 point to spend!';
                otherwise
                    app.(['abi',num2str(app.side)]).Text = [num2str(app.(app.champ).l - spent + Karma),' points to spend!'];
            end
            app.(app.champ) = StackUp(app.(app.champ),app.(['StackSlider',num2str(app.side)]).Value);
            StatLoad(app)
            if ~isempty(app.Effect)
                if ~isempty(app.abiTemp) && strcmp(app.(app.champ).ch,app.Effect(1).ch) && strcmp(app.abiTemp,app.Effect(1).key)
                    EffectLoad(app)
                end
            end
        end
        
        function EffectLoad(app)
            %This function is triggered when an ability icon is pressed and
            %in turn calls AbilityVal. The output structure is interpretted
            %into the UI
            if app.side == 1
                effect= AbilityVal(app.champDat,app.champ1,app.abiTemp,app.champ2);
                app.Effects.Text = [app.champ1.ch,'''s ',app.abiTemp,':'];
            elseif app.side == 2
                effect = AbilityVal(app.champDat,app.champ2,app.abiTemp,app.champ1);
                app.Effects.Text = [app.champ2.ch,'''s ',app.abiTemp,':'];
            end
            si = {'2','1'};
            tabCount = 0;
            for i = 1:numel(effect)
                if ~isempty(effect(i).value)
                    tabCount = tabCount + 1;
                    ref(tabCount) = i;
                    app.(['Tab',num2str(tabCount)]).Parent = app.TabGroup;
                    app.(['Tab',num2str(tabCount)]).Tooltip = effect(i).att;
                    app.(['Tab',num2str(tabCount)]).Title = effect(i).att;
                    app.(['Utility',num2str(tabCount)]).Visible = 0;
                    app.(['Enact',num2str(tabCount)]).Visible = 0;
                    
                    app.(['effectAttributeLabel',num2str(tabCount)]).Visible = 1;
                    app.(['effectAttributeLabel',num2str(tabCount)]).Text = effect(i).att;
                    
                    app.(['effectValueLabel',num2str(tabCount)]).Visible = 1;
                    app.(['effectValueLabel',num2str(tabCount)]).Text = num2str(effect(i).value,'%.1f');
                    
                    if contains(effect(i).att,'amage')
                        app.(['effectDealtLabel',num2str(tabCount)]).Visible = 1;
                        app.(['effectDealtLabel',num2str(tabCount)]).Text = [app.(['champ',si{app.side}]).ch,' would take ',...
                            num2str(effect(i).dealt,'%.1f'), ' damage.'];
                        app.(['Enact',num2str(tabCount)]).Visible = 1;
                    else
                        app.(['effectDealtLabel',num2str(tabCount)]).Visible = 0;
                    end
                end
            end
            for i = tabCount+1:10
                app.(['Tab',num2str(i)]).Parent = [];
                app.(['Tab',num2str(i)]).Tooltip = '';
                app.(['Tab',num2str(i)]).Title = '';
                app.(['Utility',num2str(i)]).Visible = 0;
                app.(['Enact',num2str(i)]).Visible = 0;
                app.(['effectAttributeLabel',num2str(i)]).Visible = 0;
                app.(['effectValueLabel',num2str(i)]).Visible = 0;
                app.(['effectDealtLabel',num2str(i)]).Visible = 0;
            end
            if tabCount == 0
                i = 1;
                app.Tab1.Parent = app.TabGroup;
                app.Utility1.Visible = 1;
                app.Utility1.Text = 'This is a utility spell!';
                app.(['Tab',num2str(i)]).Tooltip = '';
                app.(['Tab',num2str(i)]).Title = '';
                app.(['Enact',num2str(i)]).Visible = 0;
                app.(['effectAttributeLabel',num2str(i)]).Visible = 0;
                app.(['effectValueLabel',num2str(i)]).Visible = 0;
                app.(['effectDealtLabel',num2str(i)]).Visible = 0;
                app.Effect = [];
                return
            end
            app.Effect = effect;
            app.Ref = ref;
        end
        
        function MejaiSlider(app)
            sli = 'no';
            if any(contains(app.(app.champ).inv,'Dark Seal'))
                sli = 'ds';
            end
            if any(contains(app.(app.champ).inv,'Mejai'))
                sli = 'ms';
            end
            switch sli
                case 'no'
                    app.(['MejaiSlider',num2str(app.side)]).Visible = 0;
                    app.(['MejaiSlider',num2str(app.side),'Label']).Visible = 0;
                case 'ds'
                    app.(['MejaiSlider',num2str(app.side)]).Visible = 1;
                    app.(['MejaiSlider',num2str(app.side),'Label']).Visible = 1;
                    app.(['MejaiSlider',num2str(app.side),'Label']).Text = 'Dark Seal';
                    app.(['MejaiSlider',num2str(app.side)]).Limits = [0,10];
                case 'ms'
                    app.(['MejaiSlider',num2str(app.side)]).Visible = 1;
                    app.(['MejaiSlider',num2str(app.side),'Label']).Visible = 1;
                    app.(['MejaiSlider',num2str(app.side),'Label']).Text = 'Mejai''s';
                    app.(['MejaiSlider',num2str(app.side)]).Limits = [0,25];
            end
        end
    end
    
    methods (Access = private)
        function HealthLoad(app,tab)
            chRef = {'champ1','champ2'};
            chName = {app.champ1.ch,app.champ2.ch};
            chTarg = chRef{~strcmp(chName,app.Effect(1).ch)};
            app.(chTarg).stats.healthCurrent = app.(chTarg).stats.healthCurrent ...
                - app.Effect(app.Ref(tab)).dealt;
            if app.(chTarg).stats.healthCurrent < 0
                app.(chTarg).stats.healthCurrent = 0;
            end
            QuickHealth(app,chTarg)
            EffectLoad(app)
        end
        
        function QuickHealth(app,chTarg)
            %Updates the visual aspect of health display without changing
            %any stats. chTarg a string 'champ1' or 'champ2'
            app.([chTarg,'Health']).Text = [num2str(app.(chTarg).stats.healthCurrent,'%.0f')...
                ,' / ',num2str(app.(chTarg).stats.health,'%.0f'),' + ',...
                num2str(app.(chTarg).stats.healthRegen/5,'%.1f'),'/s'];
        end
        
        
        function StackSlider(app)
            sli = 0;
            if isfield(app.(app.champ),'Stack')
                sli = 1;
            end
            switch sli
                case 0
                    app.(['StackSlider',num2str(app.side)]).Visible = 0;
                    app.(['StackSlider',num2str(app.side),'Label']).Visible = 0;
                case 1
                    app.(['StackSlider',num2str(app.side)]).Visible = 1;
                    app.(['StackSlider',num2str(app.side),'Label']).Visible = 1;
                    app.(['StackSlider',num2str(app.side),'Label']).Text = app.(app.champ).Stack.name;
                    app.(['StackSlider',num2str(app.side)]).Value = 0;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load itemdat.mat itemdat itemkey itemname
            load champdat.mat champdat
            %Property definitions
            app.champDat = champdat;
            app.itemDat = itemdat;
            app.itemName = itemname;
            app.itemKey = itemkey;
            %Hiding tabs
            for i = 2:10
                app.(['Tab',num2str(i)]).Parent = [];
            end
            %Initizalizing champ structures and misc visuals
            ChampGen1(app)
            MejaiSlider(app)
            ChampGen2(app)
            MejaiSlider(app)
        end

        % Value changed function: ChampionName
        function ChampGen1(app, event)
            ch = app.ChampionName.Value;
            app.champ1 = ChampGen(app.itemDat,app.champDat,ch);
            app.Image.ImageSource = fullfile('champion',[ch,'.png']);
            app.champ = 'champ1'; app.side = 1;
            abi = 'QWER';
            for i = 1:length(abi)
                app.([abi(i),'1']).ImageSource = fullfile('spell',[ch,abi(i),'.png']);
                app.champ1.abi.(abi(i)) = 0;
                app.([abi(i),'Spinner1']).Limits = [0,1];
                app.([abi(i),'Spinner1']).Value = 0;
            end
            for i = 1:6
                app.(['Slot1',num2str(i)]).Value = 'None';
                slotImName = ['Slot',num2str(app.side),num2str(i),'Im'];
                app.(slotImName).ImageSource = fullfile('square.png'); 
            end
            %Loading resource bars
            app.(['resourceFull',num2str(app.side)]).Visible = 1;
            switch app.champDat.(app.(app.champ).ch).resource
                case 'MANA'
                    res = 'mana';
                case 'ENERGY'
                    res = 'energy';
                case 'NONE'
                    res = 'mana';
                    app.(['resourceFull',num2str(app.side)]).Visible = 0;
                otherwise
                    res = 'rage';
            end
            app.(['resourceFull',num2str(app.side)]).ImageSource = ...
                fullfile('StatMods',[res,'Full.png']);
            app.(['healthFull',num2str(app.side)]).ImageSource = ...
                fullfile('StatMods','healthFull.png');
            LevelUp1(app)
            StackSlider(app)
        end

        % Value changed function: ChampionName2
        function ChampGen2(app, event)
            ch = app.ChampionName2.Value;
            app.champ2 = ChampGen(app.itemDat,app.champDat,ch);
            app.Image_2.ImageSource = fullfile('champion',[ch,'.png']);
            app.champ = 'champ2'; app.side = 2;
            abi = 'QWER';
            for i = 1:length(abi)
                app.([abi(i),'2']).ImageSource = fullfile('spell',[ch,abi(i),'.png']);
                app.([abi(i),'Spinner2']).Limits = [0,1];
                app.([abi(i),'Spinner2']).Value = 0;
            end
            for i = 1:6
                app.(['Slot2',num2str(i)]).Value = 'None';
                slotImName = ['Slot',num2str(app.side),num2str(i),'Im'];
                app.(slotImName).ImageSource = 'square.png';
            end
            %Loading resource bars
            app.(['resourceFull',num2str(app.side)]).Visible = 1;
            switch app.champDat.(app.(app.champ).ch).resource
                case 'MANA'
                    res = 'mana';
                case 'ENERGY'
                    res = 'energy';
                case 'NONE'
                    res = 'mana';
                    app.(['resourceFull',num2str(app.side)]).Visible = 0;
                otherwise
                    res = 'rage';
            end
            app.(['resourceFull',num2str(app.side)]).ImageSource = ...
                fullfile('StatMods',[res,'Full.png']);
            app.(['healthFull',num2str(app.side)]).ImageSource = ...
                fullfile('StatMods','healthFull.png');
            LevelUp2(app)
            StackSlider(app)
        end

        % Value changed function: LvlDrop1
        function LevelUp1(app, event)
            l = str2double(app.LvlDrop1.Value);
            abi = 'QWER';
            if l < app.champ1.l
                for i = 1:length(abi)
                    app.([abi(i),'Spinner1']).Value = app.([abi(i),'Spinner1']).Limits(1);
                    app.champ1.abi.(abi(i)) = app.([abi(i),'Spinner1']).Value;
                end
            end
            app.champ1 = LevelUp(app.champDat,app.champ1,l);
            app.champ = 'champ1'; app.side = 1;
            app.champ1.stats.healthCurrent = app.champ1.stats.health;
            QuickHealth(app,app.champ)
            AbiLoad(app)
            StatLoad(app)
        end

        % Value changed function: LvlDrop2
        function LevelUp2(app, event)
            l = str2double(app.LvlDrop2.Value);
            abi = 'QWER';
            if l < app.champ2.l
                for i = 1:length(abi)
                    app.([abi(i),'Spinner2']).Value = app.([abi(i),'Spinner2']).Limits(1);
                    app.champ2.abi.(abi(i)) = app.([abi(i),'Spinner2']).Value;
                end
            end
            app.champ2 = LevelUp(app.champDat,app.champ2,l);
            app.champ = 'champ2'; app.side = 2;
            app.champ2.stats.healthCurrent = app.champ2.stats.health;
            QuickHealth(app,app.champ)
            AbiLoad(app)
            StatLoad(app)
        end

        % Value changed function: Slot11
        function Inv11(app, event)
            app.invTemp = app.Slot11.Value;
            app.champ = 'champ1'; app.slot = 1; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot12
        function Inv12(app, event)
            app.invTemp = app.Slot12.Value;
            app.champ = 'champ1'; app.slot = 2; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot13
        function Inv13(app, event)
            app.invTemp = app.Slot13.Value;
            app.champ = 'champ1'; app.slot = 3; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot14
        function Inv14(app, event)
            app.invTemp = app.Slot14.Value;
            app.champ = 'champ1'; app.slot = 4; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot15
        function Inv15(app, event)
            app.invTemp = app.Slot15.Value;
            app.champ = 'champ1'; app.slot = 5; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot16
        function Inv16(app, event)
            app.invTemp = app.Slot16.Value;
            app.champ = 'champ1'; app.slot = 6; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot21
        function Inv21(app, event)
            app.invTemp = app.Slot21.Value;
            app.champ = 'champ2'; app.slot = 1; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: Slot22
        function Inv22(app, event)
            app.invTemp = app.Slot22.Value;
            app.champ = 'champ2'; app.slot = 2; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: Slot23
        function Inv23(app, event)
            app.invTemp = app.Slot23.Value;
            app.champ = 'champ2'; app.slot = 3; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: Slot24
        function Inv24(app, event)
            app.invTemp = app.Slot24.Value;
            app.champ = 'champ2'; app.slot = 4; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: Slot25
        function Inv25(app, event)
            app.invTemp = app.Slot25.Value;
            app.champ = 'champ2'; app.slot = 5; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: Slot26
        function Inv26(app, event)
            app.invTemp = app.Slot26.Value;
            app.champ = 'champ2'; app.slot = 6; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: ESpinner1, ESpinner2, QSpinner1, 
        % QSpinner2, RSpinner1, RSpinner2, WSpinner1, WSpinner2
        function AbiUpdate(app, event)
            tag = event.Source.Tag;
            app.(['champ',tag(2)]).abi.(tag(1)) = app.([tag(1),'Spinner',tag(2)]).Value;
            app.side = str2double(tag(2)); app.champ = ['champ',tag(2)];
            AbiLoad(app)
        end

        % Image clicked function: Q1
        function Q1ImageClicked(app, event)
            app.abiTemp = 'Q';
            app.side = 1; app.champ = 'champ1';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: W1
        function W1ImageClicked(app, event)
            app.abiTemp = 'W';
            app.side = 1; app.champ = 'champ1';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: E1
        function E1ImageClicked(app, event)
            app.abiTemp = 'E';
            app.side = 1; app.champ = 'champ1';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: R1
        function R1ImageClicked(app, event)
            app.abiTemp = 'R';
            app.side = 1; app.champ = 'champ1';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: Q2
        function Q2ImageClicked(app, event)
            app.abiTemp = 'Q';
            app.side = 2; app.champ = 'champ2';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: W2
        function W2ImageClicked(app, event)
            app.abiTemp = 'W';
            app.side = 2; app.champ = 'champ2';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: E2
        function E2ImageClicked(app, event)
            app.abiTemp = 'E';
            app.side = 2; app.champ = 'champ2';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: R2
        function R2ImageClicked(app, event)
            app.abiTemp = 'R';
            app.side = 2; app.champ = 'champ2';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Callback function
        function ButtonValueChanged(app, event)
            I = imread('/Users/ethangreen/Documents/MATLAB/Dragon/StatMods/energyFull.png');
            app.resourceFull1.ImageSource = I;
            I = imcrop(I,[0 0 200 65]);
            app.resourceFull2.ImageSource = I;
            app.resourceFull2.Position = [842 98 200 145];
            I = imread('/Users/ethangreen/Documents/MATLAB/Dragon/StatMods/healthFull.png');
            app.healthFull1.ImageSource = I;
            I = imcrop(I,[0 0 200 65]);
            app.healthFull2.ImageSource = I;
            app.healthFull2.Position = [818 174 200 100];
        end

        % Value changed function: Enact1
        function Enact1ValueChanged(app, event)
            HealthLoad(app,1)
        end

        % Value changed function: Enact2
        function Enact2ValueChanged(app, event)
            HealthLoad(app,2)
        end

        % Value changed function: Enact3
        function Enact3ValueChanged(app, event)
            HealthLoad(app,3)
        end

        % Value changed function: Enact4
        function Enact4ValueChanged(app, event)
            HealthLoad(app,4)
        end

        % Value changed function: Enact5
        function Enact5ValueChanged(app, event)
            HealthLoad(app,5)
        end

        % Value changed function: Enact6
        function Enact6ValueChanged(app, event)
            HealthLoad(app,6)
        end

        % Value changed function: Enact7
        function Enact7ValueChanged(app, event)
            HealthLoad(app,7)
        end

        % Value changed function: Enact8
        function Enact8ValueChanged(app, event)
            HealthLoad(app,8)
        end

        % Value changed function: Enact9
        function Enact9ValueChanged(app, event)
            HealthLoad(app,9)
        end

        % Value changed function: Enact10
        function Enact10ValueChanged(app, event)
            HealthLoad(app,10)
        end

        % Image clicked function: Redo1
        function Redo1ImageClicked(app, event)
            app.champ1.stats.healthCurrent = app.champ1.stats.health;
            app.side = 2; app.champ = 'champ2';
            QuickHealth(app,'champ1')
            if ~isempty(app.abiTemp)
                EffectLoad(app)
            end
        end

        % Image clicked function: Redo2
        function Redo2ImageClicked(app, event)
            app.champ2.stats.healthCurrent = app.champ2.stats.health;
            app.side = 1; app.champ = 'champ1';
            QuickHealth(app,'champ2')
            if ~isempty(app.abiTemp)
                EffectLoad(app)
            end
        end

        % Value changed function: MejaiSlider1
        function MejaiSlider1ValueChanged(app, event)
            value = app.MejaiSlider1.Value;
            app.side = 1;
            app.champ = 'champ1';
            if ~isfield(app.(app.champ).pass,'stack')
                app.(app.champ).pass.stack = 0;
            end
            app.(app.champ).stats.abilityPower = app.(app.champ).stats.abilityPower - ...
                app.(app.champ).pass.stack*app.(app.champ).pass.mod;
            app.(app.champ).pass.stack = value;
            app.(app.champ).stats.abilityPower = app.(app.champ).stats.abilityPower + ...
                app.(app.champ).pass.stack*app.(app.champ).pass.mod;
            AbiLoad(app)
        end

        % Value changed function: MejaiSlider2
        function MejaiSlider2ValueChanged(app, event)
            value = app.MejaiSlider2.Value;
            app.side = 2;
            app.champ = 'champ2';
            if ~isfield(app.(app.champ).pass,'stack')
                app.(app.champ).pass.stack = 0;
            end
            app.(app.champ).stats.abilityPower = app.(app.champ).stats.abilityPower - ...
                app.(app.champ).pass.stack*app.(app.champ).pass.mod;
            app.(app.champ).pass.stack = value;
            app.(app.champ).stats.abilityPower = app.(app.champ).stats.abilityPower + ...
                app.(app.champ).pass.stack*app.(app.champ).pass.mod;
            AbiLoad(app)
        end

        % Value changed function: StackSlider1
        function StackSlider1ValueChanged(app, event)
            value = app.StackSlider1.Value;
            app.side = 1;
            app.champ = 'champ1';
            app.(app.champ) = StackUp(app.(app.champ),value);
            if ~isempty(app.abiTemp)
            EffectLoad(app)
            end
            StatLoad(app)
            app.(app.champ) = PassiveUp(app.itemDat,app.(app.champ));
        end

        % Value changed function: StackSlider2
        function StackSlider2ValueChanged(app, event)
            value = app.StackSlider2.Value;
            app.side = 2;
            app.champ = 'champ2';
            app.(app.champ) = StackUp(app.(app.champ),value);
            if ~isempty(app.abiTemp)
            EffectLoad(app)
            end
            StatLoad(app)
            app.(app.champ) = PassiveUp(app.itemDat,app.(app.champ));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.HealthLabel = uilabel(app.GridLayout);
            app.HealthLabel.Text = 'Health';
            app.ResourceLabel = uilabel(app.GridLayout);
            app.ResourceLabel.Text = 'Resource';
            app.AttackDamageLabel = uilabel(app.GridLayout);
            app.AttackDamageLabel.Text = 'Attack Damage';
            app.ArmorLabel = uilabel(app.GridLayout);
            app.ArmorLabel.Text = 'Armor';
            app.AttackSpeedLabel = uilabel(app.GridLayout);
            app.AttackSpeedLabel.Text = 'Attack Speed';
            app.CriticalChanceLabel = uilabel(app.GridLayout);
            app.CriticalChanceLabel.Text = 'Critical Chance';
            app.HealthVal = uilabel(app.GridLayout);
            app.HealthVal.Text = '602';
            app.ResourceVal = uilabel(app.GridLayout);
            app.ResourceVal.Text = '0';
            app.AttackDamageVal = uilabel(app.GridLayout);
            app.AttackDamageVal.Text = '58';
            app.ArmorVal = uilabel(app.GridLayout);
            app.ArmorVal.Text = '28';
            app.AttackSpeedVal = uilabel(app.GridLayout);
            app.AttackSpeedVal.Text = '0.66';
            app.CriticalChanceVal = uilabel(app.GridLayout);
            app.CriticalChanceVal.Text = '0%';
            app.AbilityPowerLabel = uilabel(app.GridLayout);
            app.AbilityPowerLabel.Text = 'Ability Power';
            app.MagicResistanceLabel = uilabel(app.GridLayout);
            app.MagicResistanceLabel.Text = 'Magic Resistance';
            app.AbilityHasteLabel = uilabel(app.GridLayout);
            app.AbilityHasteLabel.Text = 'Ability Haste';
            app.MovementSpeedLabel = uilabel(app.GridLayout);
            app.MovementSpeedLabel.Text = 'Movement Speed';
            app.AbilityPowerVal = uilabel(app.GridLayout);
            app.AbilityPowerVal.Text = '0';
            app.MagicResistanceVal = uilabel(app.GridLayout);
            app.MagicResistanceVal.Text = '32';
            app.AbilityHasteVal = uilabel(app.GridLayout);
            app.AbilityHasteVal.Text = '0';
            app.MovementSpeedVal = uilabel(app.GridLayout);
            app.MovementSpeedVal.Text = '340';
            app.Slot11Im = uiimage(app.GridLayout);
            app.Slot11Im.ImageSource = 'square.png';
            app.Slot12Im = uiimage(app.GridLayout);
            app.Slot12Im.ImageSource = 'square.png';
            app.Slot13Im = uiimage(app.GridLayout);
            app.Slot13Im.ImageSource = 'square.png';
            app.Slot14Im = uiimage(app.GridLayout);
            app.Slot14Im.ImageSource = 'square.png';
            app.Slot15Im = uiimage(app.GridLayout);
            app.Slot15Im.ImageSource = 'square.png';
            app.Slot16Im = uiimage(app.GridLayout);
            app.Slot16Im.ImageSource = 'square.png';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = 'Health_icon.png';
            app.Image2_2 = uiimage(app.GridLayout);
            app.Image2_2.ImageSource = 'Mana_icon.png';
            app.Image2_3 = uiimage(app.GridLayout);
            app.Image2_3.ImageSource = 'Attack_damage_icon.png';
            app.Image2_4 = uiimage(app.GridLayout);
            app.Image2_4.ImageSource = 'Armor_icon.png';
            app.Image2_5 = uiimage(app.GridLayout);
            app.Image2_5.ImageSource = 'Attack_speed_icon.png';
            app.Image2_6 = uiimage(app.GridLayout);
            app.Image2_6.ImageSource = 'Critical_strike_damage_icon.png';
            app.Image2_7 = uiimage(app.GridLayout);
            app.Image2_7.ImageSource = 'Ability_power_icon.png';
            app.Image2_8 = uiimage(app.GridLayout);
            app.Image2_8.ImageSource = 'Magic_resistance_icon.png';
            app.Image2_9 = uiimage(app.GridLayout);
            app.Image2_9.ImageSource = 'Cooldown_reduction_icon.png';
            app.Image2_10 = uiimage(app.GridLayout);
            app.Image2_10.ImageSource = 'Movement_speed_icon.png';
            app.Image_2 = uiimage(app.GridLayout);
            app.HealthLabel_2 = uilabel(app.GridLayout);
            app.HealthLabel_2.Text = 'Health';
            app.ResourceLabel_2 = uilabel(app.GridLayout);
            app.ResourceLabel_2.Text = 'Resource';
            app.AttackDamageLabel_2 = uilabel(app.GridLayout);
            app.AttackDamageLabel_2.Text = 'Attack Damage';
            app.ArmorLabel_2 = uilabel(app.GridLayout);
            app.ArmorLabel_2.Text = 'Armor';
            app.AttackSpeedLabel_2 = uilabel(app.GridLayout);
            app.AttackSpeedLabel_2.Text = 'Attack Speed';
            app.CriticalChanceLabel_2 = uilabel(app.GridLayout);
            app.CriticalChanceLabel_2.Text = 'Critical Chance';
            app.HealthVal_2 = uilabel(app.GridLayout);
            app.HealthVal_2.Text = '602';
            app.ResourceVal_2 = uilabel(app.GridLayout);
            app.ResourceVal_2.Text = '0';
            app.AttackDamageVal_2 = uilabel(app.GridLayout);
            app.AttackDamageVal_2.Text = '58';
            app.ArmorVal_2 = uilabel(app.GridLayout);
            app.ArmorVal_2.Text = '28';
            app.AttackSpeedVal_2 = uilabel(app.GridLayout);
            app.AttackSpeedVal_2.Text = '0.66';
            app.CriticalChanceVal_2 = uilabel(app.GridLayout);
            app.CriticalChanceVal_2.Text = '0%';
            app.AbilityPowerLabel_2 = uilabel(app.GridLayout);
            app.AbilityPowerLabel_2.Text = 'Ability Power';
            app.MagicResistanceLabel_2 = uilabel(app.GridLayout);
            app.MagicResistanceLabel_2.Text = 'Magic Resistance';
            app.AbilityHasteLabel_2 = uilabel(app.GridLayout);
            app.AbilityHasteLabel_2.Text = 'Ability Haste';
            app.MovementSpeedLabel_2 = uilabel(app.GridLayout);
            app.MovementSpeedLabel_2.Text = 'Movement Speed';
            app.AbilityPowerVal_2 = uilabel(app.GridLayout);
            app.AbilityPowerVal_2.Text = '0';
            app.MagicResistanceVal_2 = uilabel(app.GridLayout);
            app.MagicResistanceVal_2.Text = '32';
            app.AbilityHasteVal_2 = uilabel(app.GridLayout);
            app.AbilityHasteVal_2.Text = '0';
            app.MovementSpeedVal_2 = uilabel(app.GridLayout);
            app.MovementSpeedVal_2.Text = '340';
            app.Slot21Im = uiimage(app.GridLayout);
            app.Slot21Im.ImageSource = 'square.png';
            app.Slot22Im = uiimage(app.GridLayout);
            app.Slot22Im.ImageSource = 'square.png';
            app.Slot23Im = uiimage(app.GridLayout);
            app.Slot23Im.ImageSource = 'square.png';
            app.Slot24Im = uiimage(app.GridLayout);
            app.Slot24Im.ImageSource = 'square.png';
            app.Slot25Im = uiimage(app.GridLayout);
            app.Slot25Im.ImageSource = 'square.png';
            app.Slot26Im = uiimage(app.GridLayout);
            app.Slot26Im.ImageSource = 'square.png';
            app.Image2_11 = uiimage(app.GridLayout);
            app.Image2_11.ImageSource = 'Health_icon.png';
            app.Image2_12 = uiimage(app.GridLayout);
            app.Image2_12.ImageSource = 'Mana_icon.png';
            app.Image2_13 = uiimage(app.GridLayout);
            app.Image2_13.ImageSource = 'Attack_damage_icon.png';
            app.Image2_14 = uiimage(app.GridLayout);
            app.Image2_14.ImageSource = 'Armor_icon.png';
            app.Image2_15 = uiimage(app.GridLayout);
            app.Image2_15.ImageSource = 'Attack_speed_icon.png';
            app.Image2_16 = uiimage(app.GridLayout);
            app.Image2_16.ImageSource = 'Critical_strike_damage_icon.png';
            app.Image2_17 = uiimage(app.GridLayout);
            app.Image2_17.ImageSource = 'Ability_power_icon.png';
            app.Image2_18 = uiimage(app.GridLayout);
            app.Image2_18.ImageSource = 'Magic_resistance_icon.png';
            app.Image2_19 = uiimage(app.GridLayout);
            app.Image2_19.ImageSource = 'Cooldown_reduction_icon.png';
            app.Image2_20 = uiimage(app.GridLayout);
            app.Image2_20.ImageSource = 'Movement_speed_icon.png';
            app.Q1 = uiimage(app.GridLayout);
            app.Q1.ImageClickedFcn = createCallbackFcn(app, @Q1ImageClicked, true);
            app.W1 = uiimage(app.GridLayout);
            app.W1.ImageClickedFcn = createCallbackFcn(app, @W1ImageClicked, true);
            app.E1 = uiimage(app.GridLayout);
            app.E1.ImageClickedFcn = createCallbackFcn(app, @E1ImageClicked, true);
            app.R1 = uiimage(app.GridLayout);
            app.R1.ImageClickedFcn = createCallbackFcn(app, @R1ImageClicked, true);
            app.MagicPenLabel = uilabel(app.GridLayout);
            app.MagicPenLabel.Text = 'Magic Pen';
            app.MagicPenVal = uilabel(app.GridLayout);
            app.MagicPenVal.Text = '0/0%';
            app.ArmorPenLabel = uilabel(app.GridLayout);
            app.ArmorPenLabel.Text = 'Armor Pen';
            app.ArmorPenVal = uilabel(app.GridLayout);
            app.ArmorPenVal.Text = '0%';
            app.LethalityLabel = uilabel(app.GridLayout);
            app.LethalityLabel.Text = 'Lethality';
            app.LethalityVal = uilabel(app.GridLayout);
            app.LethalityVal.Text = '0';
            app.MagicPenLabel_2 = uilabel(app.GridLayout);
            app.MagicPenLabel_2.Text = 'Magic Pen';
            app.MagicPenVal_2 = uilabel(app.GridLayout);
            app.MagicPenVal_2.Text = '0/0%';
            app.ArmorPenLabel_2 = uilabel(app.GridLayout);
            app.ArmorPenLabel_2.Text = 'Armor Pen';
            app.ArmorPenVal_2 = uilabel(app.GridLayout);
            app.ArmorPenVal_2.Text = '0%';
            app.LethalityLabel_2 = uilabel(app.GridLayout);
            app.LethalityLabel_2.Text = 'Lethality';
            app.LethalityVal_2 = uilabel(app.GridLayout);
            app.LethalityVal_2.Text = '0';
            app.Q2 = uiimage(app.GridLayout);
            app.Q2.ImageClickedFcn = createCallbackFcn(app, @Q2ImageClicked, true);
            app.W2 = uiimage(app.GridLayout);
            app.W2.ImageClickedFcn = createCallbackFcn(app, @W2ImageClicked, true);
            app.E2 = uiimage(app.GridLayout);
            app.E2.ImageClickedFcn = createCallbackFcn(app, @E2ImageClicked, true);
            app.R2 = uiimage(app.GridLayout);
            app.R2.ImageClickedFcn = createCallbackFcn(app, @R2ImageClicked, true);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1 = uitab(app.TabGroup);
            app.effectAttributeLabel1 = uilabel(app.Tab1);
            app.effectAttributeLabel1.Visible = 'off';
            app.effectAttributeLabel1.Text = 'effectAttribute';
            app.effectValueLabel1 = uilabel(app.Tab1);
            app.effectValueLabel1.Visible = 'off';
            app.effectValueLabel1.Text = 'effectValue';
            app.effectDealtLabel1 = uilabel(app.Tab1);
            app.effectDealtLabel1.Visible = 'off';
            app.effectDealtLabel1.Text = 'effectDealt';
            app.Utility1 = uilabel(app.Tab1);
            app.Utility1.Text = 'Press an ability to simulate!';
            app.Enact1 = uibutton(app.Tab1, 'state');
            app.Enact1.ValueChangedFcn = createCallbackFcn(app, @Enact1ValueChanged, true);
            app.Enact1.Visible = 'off';
            app.Enact1.Text = 'Enact';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Tooltip = {''};
            app.effectAttributeLabel2 = uilabel(app.Tab2);
            app.effectAttributeLabel2.Visible = 'off';
            app.effectAttributeLabel2.Text = 'effectAttribute';
            app.effectValueLabel2 = uilabel(app.Tab2);
            app.effectValueLabel2.Visible = 'off';
            app.effectValueLabel2.Text = 'effectValue';
            app.effectDealtLabel2 = uilabel(app.Tab2);
            app.effectDealtLabel2.Visible = 'off';
            app.effectDealtLabel2.Text = 'effectDealt';
            app.Utility2 = uilabel(app.Tab2);
            app.Utility2.Text = 'Press an ability to simulate!';
            app.Enact2 = uibutton(app.Tab2, 'state');
            app.Enact2.ValueChangedFcn = createCallbackFcn(app, @Enact2ValueChanged, true);
            app.Enact2.Visible = 'off';
            app.Enact2.Text = 'Enact';
            app.Tab3 = uitab(app.TabGroup);
            app.effectAttributeLabel3 = uilabel(app.Tab3);
            app.effectAttributeLabel3.Visible = 'off';
            app.effectAttributeLabel3.Text = 'effectAttribute';
            app.effectValueLabel3 = uilabel(app.Tab3);
            app.effectValueLabel3.Visible = 'off';
            app.effectValueLabel3.Text = 'effectValue';
            app.effectDealtLabel3 = uilabel(app.Tab3);
            app.effectDealtLabel3.Visible = 'off';
            app.effectDealtLabel3.Text = 'effectDealt';
            app.Utility3 = uilabel(app.Tab3);
            app.Utility3.Text = 'Press an ability to simulate!';
            app.Enact3 = uibutton(app.Tab3, 'state');
            app.Enact3.ValueChangedFcn = createCallbackFcn(app, @Enact3ValueChanged, true);
            app.Enact3.Visible = 'off';
            app.Enact3.Text = 'Enact';
            app.Tab4 = uitab(app.TabGroup);
            app.effectAttributeLabel4 = uilabel(app.Tab4);
            app.effectAttributeLabel4.Visible = 'off';
            app.effectAttributeLabel4.Text = 'effectAttribute';
            app.effectValueLabel4 = uilabel(app.Tab4);
            app.effectValueLabel4.Visible = 'off';
            app.effectValueLabel4.Text = 'effectValue';
            app.effectDealtLabel4 = uilabel(app.Tab4);
            app.effectDealtLabel4.Visible = 'off';
            app.effectDealtLabel4.Text = 'effectDealt';
            app.Utility4 = uilabel(app.Tab4);
            app.Utility4.Text = 'Press an ability to simulate!';
            app.Enact4 = uibutton(app.Tab4, 'state');
            app.Enact4.ValueChangedFcn = createCallbackFcn(app, @Enact4ValueChanged, true);
            app.Enact4.Visible = 'off';
            app.Enact4.Text = 'Enact';
            app.Tab5 = uitab(app.TabGroup);
            app.effectAttributeLabel5 = uilabel(app.Tab5);
            app.effectAttributeLabel5.Visible = 'off';
            app.effectAttributeLabel5.Text = 'effectAttribute';
            app.effectValueLabel5 = uilabel(app.Tab5);
            app.effectValueLabel5.Visible = 'off';
            app.effectValueLabel5.Text = 'effectValue';
            app.effectDealtLabel5 = uilabel(app.Tab5);
            app.effectDealtLabel5.Visible = 'off';
            app.effectDealtLabel5.Text = 'effectDealt';
            app.Utility5 = uilabel(app.Tab5);
            app.Utility5.Text = 'Press an ability to simulate!';
            app.Enact5 = uibutton(app.Tab5, 'state');
            app.Enact5.ValueChangedFcn = createCallbackFcn(app, @Enact5ValueChanged, true);
            app.Enact5.Visible = 'off';
            app.Enact5.Text = 'Enact';
            app.Tab6 = uitab(app.TabGroup);
            app.effectAttributeLabel6 = uilabel(app.Tab6);
            app.effectAttributeLabel6.Visible = 'off';
            app.effectAttributeLabel6.Text = 'effectAttribute';
            app.effectValueLabel6 = uilabel(app.Tab6);
            app.effectValueLabel6.Visible = 'off';
            app.effectValueLabel6.Text = 'effectValue';
            app.effectDealtLabel6 = uilabel(app.Tab6);
            app.effectDealtLabel6.Visible = 'off';
            app.effectDealtLabel6.Text = 'effectDealt';
            app.Utility6 = uilabel(app.Tab6);
            app.Utility6.Text = 'Press an ability to simulate!';
            app.Enact6 = uibutton(app.Tab6, 'state');
            app.Enact6.ValueChangedFcn = createCallbackFcn(app, @Enact6ValueChanged, true);
            app.Enact6.Visible = 'off';
            app.Enact6.Text = 'Enact';
            app.Tab7 = uitab(app.TabGroup);
            app.effectAttributeLabel7 = uilabel(app.Tab7);
            app.effectAttributeLabel7.Visible = 'off';
            app.effectAttributeLabel7.Text = 'effectAttribute';
            app.effectValueLabel7 = uilabel(app.Tab7);
            app.effectValueLabel7.Visible = 'off';
            app.effectValueLabel7.Text = 'effectValue';
            app.effectDealtLabel7 = uilabel(app.Tab7);
            app.effectDealtLabel7.Visible = 'off';
            app.effectDealtLabel7.Text = 'effectDealt';
            app.Utility7 = uilabel(app.Tab7);
            app.Utility7.Text = 'Press an ability to simulate!';
            app.Enact7 = uibutton(app.Tab7, 'state');
            app.Enact7.ValueChangedFcn = createCallbackFcn(app, @Enact7ValueChanged, true);
            app.Enact7.Visible = 'off';
            app.Enact7.Text = 'Enact';
            app.Tab8 = uitab(app.TabGroup);
            app.effectAttributeLabel8 = uilabel(app.Tab8);
            app.effectAttributeLabel8.Visible = 'off';
            app.effectAttributeLabel8.Text = 'effectAttribute';
            app.effectValueLabel8 = uilabel(app.Tab8);
            app.effectValueLabel8.Visible = 'off';
            app.effectValueLabel8.Text = 'effectValue';
            app.effectDealtLabel8 = uilabel(app.Tab8);
            app.effectDealtLabel8.Visible = 'off';
            app.effectDealtLabel8.Text = 'effectDealt';
            app.Utility8 = uilabel(app.Tab8);
            app.Utility8.Text = 'Press an ability to simulate!';
            app.Enact8 = uibutton(app.Tab8, 'state');
            app.Enact8.ValueChangedFcn = createCallbackFcn(app, @Enact8ValueChanged, true);
            app.Enact8.Visible = 'off';
            app.Enact8.Text = 'Enact';
            app.Tab9 = uitab(app.TabGroup);
            app.effectAttributeLabel9 = uilabel(app.Tab9);
            app.effectAttributeLabel9.Visible = 'off';
            app.effectAttributeLabel9.Text = 'effectAttribute';
            app.effectValueLabel9 = uilabel(app.Tab9);
            app.effectValueLabel9.Visible = 'off';
            app.effectValueLabel9.Text = 'effectValue';
            app.effectDealtLabel9 = uilabel(app.Tab9);
            app.effectDealtLabel9.Visible = 'off';
            app.effectDealtLabel9.Text = 'effectDealt';
            app.Utility9 = uilabel(app.Tab9);
            app.Utility9.Text = 'Press an ability to simulate!';
            app.Enact9 = uibutton(app.Tab9, 'state');
            app.Enact9.ValueChangedFcn = createCallbackFcn(app, @Enact9ValueChanged, true);
            app.Enact9.Visible = 'off';
            app.Enact9.Text = 'Enact';
            app.Tab10 = uitab(app.TabGroup);
            app.effectAttributeLabel10 = uilabel(app.Tab10);
            app.effectAttributeLabel10.Visible = 'off';
            app.effectAttributeLabel10.Text = 'effectAttribute';
            app.effectValueLabel10 = uilabel(app.Tab10);
            app.effectValueLabel10.Visible = 'off';
            app.effectValueLabel10.Text = 'effectValue';
            app.effectDealtLabel10 = uilabel(app.Tab10);
            app.effectDealtLabel10.Visible = 'off';
            app.effectDealtLabel10.Text = 'effectDealt';
            app.Utility10 = uilabel(app.Tab10);
            app.Utility10.Text = 'Press an ability to simulate!';
            app.Enact10 = uibutton(app.Tab10, 'state');
            app.Enact10.ValueChangedFcn = createCallbackFcn(app, @Enact10ValueChanged, true);
            app.Enact10.Visible = 'off';
            app.Enact10.Text = 'Enact';
            app.Slot11 = uidropdown(app.GridLayout);
            app.Slot11.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot11.Editable = 'on';
            app.Slot11.ValueChangedFcn = createCallbackFcn(app, @Inv11, true);
            app.Slot11.Value = 'None';
            app.Slot12 = uidropdown(app.GridLayout);
            app.Slot12.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot12.Editable = 'on';
            app.Slot12.ValueChangedFcn = createCallbackFcn(app, @Inv12, true);
            app.Slot12.Value = 'None';
            app.Slot13 = uidropdown(app.GridLayout);
            app.Slot13.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot13.Editable = 'on';
            app.Slot13.ValueChangedFcn = createCallbackFcn(app, @Inv13, true);
            app.Slot13.Value = 'None';
            app.Slot14 = uidropdown(app.GridLayout);
            app.Slot14.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot14.Editable = 'on';
            app.Slot14.ValueChangedFcn = createCallbackFcn(app, @Inv14, true);
            app.Slot14.Value = 'None';
            app.Slot15 = uidropdown(app.GridLayout);
            app.Slot15.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot15.Editable = 'on';
            app.Slot15.ValueChangedFcn = createCallbackFcn(app, @Inv15, true);
            app.Slot15.Value = 'None';
            app.Slot16 = uidropdown(app.GridLayout);
            app.Slot16.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot16.Editable = 'on';
            app.Slot16.ValueChangedFcn = createCallbackFcn(app, @Inv16, true);
            app.Slot16.Value = 'None';
            app.Slot21 = uidropdown(app.GridLayout);
            app.Slot21.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot21.Editable = 'on';
            app.Slot21.ValueChangedFcn = createCallbackFcn(app, @Inv21, true);
            app.Slot21.Value = 'None';
            app.Slot22 = uidropdown(app.GridLayout);
            app.Slot22.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot22.Editable = 'on';
            app.Slot22.ValueChangedFcn = createCallbackFcn(app, @Inv22, true);
            app.Slot22.Value = 'None';
            app.Slot23 = uidropdown(app.GridLayout);
            app.Slot23.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot23.Editable = 'on';
            app.Slot23.ValueChangedFcn = createCallbackFcn(app, @Inv23, true);
            app.Slot23.Value = 'None';
            app.Slot24 = uidropdown(app.GridLayout);

            app.Slot24.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot24.Editable = 'on';
            app.Slot24.ValueChangedFcn = createCallbackFcn(app, @Inv24, true);
            app.Slot24.Value = 'None';
            app.Slot25 = uidropdown(app.GridLayout);
            app.Slot25.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot25.Editable = 'on';
            app.Slot25.ValueChangedFcn = createCallbackFcn(app, @Inv25, true);
            app.Slot25.Value = 'None';
            app.Slot26 = uidropdown(app.GridLayout);
            app.Slot26.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot26.Editable = 'on';
            app.Slot26.ValueChangedFcn = createCallbackFcn(app, @Inv26, true);
            app.Slot26.Value = 'None';
            app.ChampionName = uidropdown(app.GridLayout);
            app.ChampionName.Items = {'Aatrox', 'Ahri', 'Akali', 'Alistar', 'Amumu', 'Anivia', 'Annie', 'Aphelios', 'Ashe', 'AurelionSol', 'Azir', 'Bard', 'Blitzcrank', 'Brand', 'Braum', 'Caitlyn', 'Camille', 'Cassiopeia', 'Chogath', 'Corki', 'Darius', 'Diana', 'DrMundo', 'Draven', 'Ekko', 'Elise', 'Evelynn', 'Ezreal', 'Fiddlesticks', 'Fiora', 'Fizz', 'Galio', 'Gangplank', 'Garen', 'Gnar', 'Gragas', 'Graves', 'Hecarim', 'Heimerdinger', 'Illaoi', 'Irelia', 'Ivern', 'Janna', 'JarvanIV', 'Jax', 'Jayce', 'Jhin', 'Jinx', 'Kaisa', 'Kalista', 'Karma', 'Karthus', 'Kassadin', 'Katarina', 'Kayle', 'Kayn', 'Kennen', 'Khazix', 'Kindred', 'Kled', 'KogMaw', 'Leblanc', 'LeeSin', 'Leona', 'Lillia', 'Lissandra', 'Lucian', 'Lulu', 'Lux', 'Malphite', 'Malzahar', 'Maokai', 'MasterYi', 'MissFortune', 'Mordekaiser', 'Morgana', 'Nami', 'Nasus', 'Nautilus', 'Neeko', 'Nidalee', 'Nocturne', 'Nunu', 'Olaf', 'Orianna', 'Ornn', 'Pantheon', 'Poppy', 'Pyke', 'Qiyana', 'Quinn', 'Rakan', 'Rammus', 'RekSai', 'Rell', 'Renekton', 'Rengar', 'Riven', 'Rumble', 'Ryze', 'Samira', 'Sejuani', 'Senna', 'Seraphine', 'Sett', 'Shaco', 'Shen', 'Shyvana', 'Singed', 'Sion', 'Sivir', 'Skarner', 'Sona', 'Soraka', 'Swain', 'Sylas', 'Syndra', 'TahmKench', 'Taliyah', 'Talon', 'Taric', 'Teemo', 'Thresh', 'Tristana', 'Trundle', 'Tryndamere', 'TwistedFate', 'Twitch', 'Udyr', 'Urgot', 'Varus', 'Vayne', 'Veigar', 'Velkoz', 'Vi', 'Viktor', 'Vladimir', 'Volibear', 'Warwick', 'MonkeyKing', 'Xayah', 'Xerath', 'XinZhao', 'Yasuo', 'Yone', 'Yorick', 'Yuumi', 'Zac', 'Zed', 'Ziggs', 'Zilean', 'Zoe', 'Zyra'};
            app.ChampionName.Editable = 'on';
            app.ChampionName.ValueChangedFcn = createCallbackFcn(app, @ChampGen1, true);
            app.ChampionName.Value = 'Lux';
            app.ChampionName2 = uidropdown(app.GridLayout);
            app.ChampionName2.Items = {'Aatrox', 'Ahri', 'Akali', 'Alistar', 'Amumu', 'Anivia', 'Annie', 'Aphelios', 'Ashe', 'AurelionSol', 'Azir', 'Bard', 'Blitzcrank', 'Brand', 'Braum', 'Caitlyn', 'Camille', 'Cassiopeia', 'Chogath', 'Corki', 'Darius', 'Diana', 'DrMundo', 'Draven', 'Ekko', 'Elise', 'Evelynn', 'Ezreal', 'Fiddlesticks', 'Fiora', 'Fizz', 'Galio', 'Gangplank', 'Garen', 'Gnar', 'Gragas', 'Graves', 'Hecarim', 'Heimerdinger', 'Illaoi', 'Irelia', 'Ivern', 'Janna', 'JarvanIV', 'Jax', 'Jayce', 'Jhin', 'Jinx', 'Kaisa', 'Kalista', 'Karma', 'Karthus', 'Kassadin', 'Katarina', 'Kayle', 'Kayn', 'Kennen', 'Khazix', 'Kindred', 'Kled', 'KogMaw', 'Leblanc', 'LeeSin', 'Leona', 'Lillia', 'Lissandra', 'Lucian', 'Lulu', 'Lux', 'Malphite', 'Malzahar', 'Maokai', 'MasterYi', 'MissFortune', 'Mordekaiser', 'Morgana', 'Nami', 'Nasus', 'Nautilus', 'Neeko', 'Nidalee', 'Nocturne', 'Nunu', 'Olaf', 'Orianna', 'Ornn', 'Pantheon', 'Poppy', 'Pyke', 'Qiyana', 'Quinn', 'Rakan', 'Rammus', 'RekSai', 'Rell', 'Renekton', 'Rengar', 'Riven', 'Rumble', 'Ryze', 'Samira', 'Sejuani', 'Senna', 'Seraphine', 'Sett', 'Shaco', 'Shen', 'Shyvana', 'Singed', 'Sion', 'Sivir', 'Skarner', 'Sona', 'Soraka', 'Swain', 'Sylas', 'Syndra', 'TahmKench', 'Taliyah', 'Talon', 'Taric', 'Teemo', 'Thresh', 'Tristana', 'Trundle', 'Tryndamere', 'TwistedFate', 'Twitch', 'Udyr', 'Urgot', 'Varus', 'Vayne', 'Veigar', 'Velkoz', 'Vi', 'Viktor', 'Vladimir', 'Volibear', 'Warwick', 'MonkeyKing', 'Xayah', 'Xerath', 'XinZhao', 'Yasuo', 'Yone', 'Yorick', 'Yuumi', 'Zac', 'Zed', 'Ziggs', 'Zilean', 'Zoe', 'Zyra'};
            app.ChampionName2.Editable = 'on';
            app.ChampionName2.ValueChangedFcn = createCallbackFcn(app, @ChampGen2, true);
            app.ChampionName2.Value = 'Malzahar';
            app.abi1 = uilabel(app.GridLayout);
            app.abi1.Text = '1 point to spend!';
            app.abi2 = uilabel(app.GridLayout);
            app.abi2.Text = '1 point to spend!';
            app.healthFull1 = uiimage(app.GridLayout);
            app.resourceFull1 = uiimage(app.GridLayout);
            app.healthFull2 = uiimage(app.GridLayout);
            app.resourceFull2 = uiimage(app.GridLayout);
            app.Effects = uilabel(app.GridLayout);
            app.Effects.Text = 'Effects:';
            app.champ2Health = uilabel(app.GridLayout);
            app.champ2Health.Text = '';
            app.champ1Health = uilabel(app.GridLayout);
            app.champ1Health.Text = '';
            app.Redo1 = uiimage(app.GridLayout);
            app.Redo1.ImageClickedFcn = createCallbackFcn(app, @Redo1ImageClicked, true);
            app.Redo1.ImageSource = 'Redo.png';
            app.Redo2 = uiimage(app.GridLayout);
            app.Redo2.ImageClickedFcn = createCallbackFcn(app, @Redo2ImageClicked, true);
            app.Redo2.ImageSource = 'Redo.png';
            app.LvlDropDownLabel = uilabel(app.GridLayout);
            app.LvlDropDownLabel.Text = 'Lvl.';
            app.LvlDrop1 = uidropdown(app.GridLayout);
            app.LvlDrop1.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.LvlDrop1.Editable = 'on';
            app.LvlDrop1.ValueChangedFcn = createCallbackFcn(app, @LevelUp1, true);
            app.LvlDrop1.Value = '5';
            app.LvlDropDownLabel_2 = uilabel(app.GridLayout);
            app.LvlDropDownLabel_2.Text = 'Lvl.';
            app.LvlDrop2 = uidropdown(app.GridLayout);
            app.LvlDrop2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.LvlDrop2.Editable = 'on';
            app.LvlDrop2.ValueChangedFcn = createCallbackFcn(app, @LevelUp2, true);
            app.LvlDrop2.Value = '7';
            app.QSpinnerLabel = uilabel(app.GridLayout);
            app.QSpinnerLabel.Text = 'Q';
            app.QSpinner1 = uispinner(app.GridLayout);
            app.QSpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.QSpinner1.Tag = 'Q1';
            app.QSpinner1.Value = 1;
            app.ESpinnerLabel = uilabel(app.GridLayout);
            app.ESpinnerLabel.Text = 'E';
            app.ESpinner1 = uispinner(app.GridLayout);
            app.ESpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.ESpinner1.Tag = 'E1';
            app.WSpinnerLabel = uilabel(app.GridLayout);
            app.WSpinnerLabel.Text = 'W';
            app.WSpinner1 = uispinner(app.GridLayout);
            app.WSpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.WSpinner1.Tag = 'W1';
            app.RSpinnerLabel = uilabel(app.GridLayout);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner1 = uispinner(app.GridLayout);
            app.RSpinner1.Limits = [0 1];
            app.RSpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.RSpinner1.Tag = 'R1';
            app.QSpinner_2Label = uilabel(app.GridLayout);
            app.QSpinner_2Label.Text = 'Q';
            app.QSpinner2 = uispinner(app.GridLayout);
            app.QSpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.QSpinner2.Tag = 'Q2';
            app.ESpinner_2Label = uilabel(app.GridLayout);
            app.ESpinner_2Label.Text = 'E';
            app.ESpinner2 = uispinner(app.GridLayout);
            app.ESpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.ESpinner2.Tag = 'E2';
            app.ESpinner2.Value = 1;
            app.WSpinner_2Label = uilabel(app.GridLayout);
            app.WSpinner_2Label.Text = 'W';
            app.WSpinner2 = uispinner(app.GridLayout);
            app.WSpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.WSpinner2.Tag = 'W2';
            app.RSpinner_2Label = uilabel(app.GridLayout);
            app.RSpinner_2Label.Text = 'R';
            app.RSpinner2 = uispinner(app.GridLayout);
            app.RSpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.RSpinner2.Tag = 'R2';
            app.Image21 = uiimage(app.GridLayout);
            app.Image21.ImageSource = 'Magic_penetration_icon.png';
            app.Image22 = uiimage(app.GridLayout);
            app.Image22.ImageSource = 'Armor_penetration_icon.png';
            app.Image22_2 = uiimage(app.GridLayout);
            app.Image22_2.ImageSource = 'Armor_penetration_icon.png';
            app.Image22_3 = uiimage(app.GridLayout);
            app.Image22_3.ImageSource = 'Armor_penetration_icon.png';
            app.Image22_4 = uiimage(app.GridLayout);
            app.Image22_4.ImageSource = 'Armor_penetration_icon.png';
            app.Image21_2 = uiimage(app.GridLayout);
            app.Image21_2.ImageSource = 'Magic_penetration_icon.png';
            app.MejaiSlider1Label = uilabel(app.GridLayout);
            app.MejaiSlider1Label.Text = 'Spinner';
            app.MejaiSlider1 = uispinner(app.GridLayout);
            app.MejaiSlider1.ValueChangedFcn = createCallbackFcn(app, @MejaiSlider1ValueChanged, true);
            app.StackSlider1Label = uilabel(app.GridLayout);
            app.StackSlider1Label.Text = 'Spinner';
            app.StackSlider1 = uispinner(app.GridLayout);
            app.StackSlider1.ValueChangedFcn = createCallbackFcn(app, @StackSlider1ValueChanged, true);
            app.MejaiSlider2Label = uilabel(app.GridLayout);
            app.MejaiSlider2Label.Text = 'Spinner';
            app.MejaiSlider2 = uispinner(app.GridLayout);
            app.MejaiSlider2.ValueChangedFcn = createCallbackFcn(app, @MejaiSlider2ValueChanged, true);
            app.StackSlider2Label = uilabel(app.GridLayout);
            app.StackSlider2Label.Text = 'Spinner';
            app.StackSlider2 = uispinner(app.GridLayout);
            app.StackSlider2.ValueChangedFcn = createCallbackFcn(app, @StackSlider2ValueChanged, true);
            app.Patch111Label = uilabel(app.GridLayout);
            app.Patch111Label.Text = 'Patch 11.1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoubleChamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end